classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        SelectAFMdatamatfileButton   matlab.ui.control.Button
        SliderCol                    matlab.ui.control.Slider
        Label                        matlab.ui.control.Label
        SliderRow                    matlab.ui.control.Slider
        RowEditFieldLabel            matlab.ui.control.Label
        RowEditField                 matlab.ui.control.EditField
        ColEditFieldLabel            matlab.ui.control.Label
        ColEditField                 matlab.ui.control.EditField
        SliderCP                     matlab.ui.control.Slider
        OverwriteCPButton            matlab.ui.control.Button
        CurrentFileTextAreaLabel     matlab.ui.control.Label
        CurrentFileTextArea          matlab.ui.control.TextArea
        HeightOrModulus              matlab.ui.control.ToggleSwitch
        UIAxes_4                     matlab.ui.control.UIAxes
        IndentationDepthnmEditFieldLabel  matlab.ui.control.Label
        IndentationDepthnmEditField  matlab.ui.control.NumericEditField
        LowerCLEditFieldLabel        matlab.ui.control.Label
        LowerCLEditField             matlab.ui.control.NumericEditField
        UpperCLEditFieldLabel        matlab.ui.control.Label
        UpperCLEditField             matlab.ui.control.NumericEditField
        NewModulusMapButton          matlab.ui.control.Button
        UpdateClimitsButton          matlab.ui.control.Button
        AxisAutoButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        AFMdata % structure containing loaded AFM data
        yl % yline
        xl % xline
        cpline %cp line
        fh % file handle of .mat file
        
        CurrentCP % current contact point from the CP slider
        CurrentD % current D vector from CP slider
        CurrentF % current F vector from CP slider
        CurrentPWE % current point wise modulus from CP slider
        CurrentE % current final modulus from CP slider
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectAFMdatamatfileButton
        function SelectAFMdatamatfileButtonPushed(app, event)
            fh = uigetfile();
            app.AFMdata = load(fh);
            app.fh = fh;
            value = app.HeightOrModulus.Value;
            if strcmp(value,"Height")
                imagesc(app.UIAxes,app.AFMdata.Height_Matrix);
                title(app.UIAxes,'Relative Height (nm)')
                c = colorbar(app.UIAxes); c.Label.String = 'nm';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            elseif strcmp(value,"Modulus")
                imagesc(app.UIAxes,app.AFMdata.E_Matrix);
                title(app.UIAxes,'Elastic Modulus (kPa)')
                c = colorbar(app.UIAxes); c.Label.String = 'kPa';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            else
                warning("This case shouldn't happen")
            end
            [nrows,ncols] = size(app.AFMdata.Height_Matrix);
            app.SliderRow.Limits = [1 nrows];
            app.SliderRow.MajorTicks = 1:nrows;
            app.SliderCol.Limits = [1 ncols];
            app.SliderCol.MajorTicks = 1:ncols;
            
            % Update second graph with raw curve and CP
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            title(app.UIAxes_2,'Raw Force Curve')
            xlabel(app.UIAxes_2,'Extension (nm)')
            ylabel(app.UIAxes_2,'Deflection(nm)')
            plot(app.UIAxes_2,x,y,'*')
            thisCP = app.AFMdata.CP_Matrix(round(app.SliderRow.Value),round(app.SliderCol.Value));
            ndx_CP = find(x == thisCP);
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,x(ndx_CP),y(ndx_CP),'ko','markersize',10);
            legend(app.UIAxes_2,'Deflection','CP','location','best')
            % Limits will not work with single precision, convert to double
            if length(x)<0
                app.SliderCP.Limits = double([0 1]);
            else
                app.SliderCP.Limits = double([x(1) x(end)]);
            end

            if thisCP == 0 || isempty(ndx_CP) || ndx_CP < 1 || ndx_CP > length(x)
                app.SliderCP.Value = double(sum(app.SliderCP.Limits));
            else
                app.SliderCP.Value = double(x(ndx_CP));
            end
            
            
            D = app.AFMdata.D_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            E = app.AFMdata.PWE_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            semilogy(app.UIAxes_3,D(1:end),real(E(1:end)),'*')
            title(app.UIAxes_3,'Pointwise Modulus')
            ylabel(app.UIAxes_3,'E (kPa)')
            xlabel(app.UIAxes_3,'Depth (nm)')
            grid(app.UIAxes_3,'on')
            app.CurrentFileTextArea.Value = fh;
            
            delete(app.yl)
            app.yl = yline(app.UIAxes,app.SliderRow.Value);
            delete(app.xl)
            app.xl = xline(app.UIAxes,app.SliderCol.Value);


        end

        % Value changing function: SliderRow
        function SliderRowValueChanging(app, event)
            changingValue = round(event.Value);
            x = app.AFMdata.Ext_Matrix{changingValue,round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{changingValue,round(app.SliderCol.Value)};
            hold(app.UIAxes_2,'off')
            plot(app.UIAxes_2,x,y,'*')
            thisCP = app.AFMdata.CP_Matrix(changingValue,round(app.SliderCol.Value));
            ndx_CP = find(x == thisCP);
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,x(ndx_CP),y(ndx_CP),'ko','markersize',10);
            legend(app.UIAxes_2,'Deflection','CP','location','best')
            if length(x)<0 || isempty(x)
                app.SliderCP.Limits = double([0 1]);
            else
                app.SliderCP.Limits = double([x(1) x(end)]);
            end

            if thisCP == 0 || isempty(ndx_CP) || ndx_CP < 1 || ndx_CP > length(x)
                app.SliderCP.Value = double(sum(app.SliderCP.Limits));
            else
                app.SliderCP.Value = double(x(ndx_CP));
            end

            D = app.AFMdata.D_Matrix{changingValue,round(app.SliderCol.Value)};
            E = app.AFMdata.PWE_Matrix{changingValue,round(app.SliderCol.Value)};
            semilogy(app.UIAxes_3,D(1:end),real(E(1:end)),'*')


            delete(app.yl)
            app.yl = yline(app.UIAxes,changingValue);

        end

        % Value changing function: SliderCol
        function SliderColValueChanging(app, event)
            changingValue = round(event.Value);
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value),changingValue};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value),changingValue};
            hold(app.UIAxes_2,'off')
            plot(app.UIAxes_2,x,y,'*')
            thisCP = app.AFMdata.CP_Matrix(round(app.SliderRow.Value),changingValue);
            ndx_CP = find(x == thisCP);
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,x(ndx_CP),y(ndx_CP),'ko','markersize',10);
            legend(app.UIAxes_2,'Deflection','CP','location','best')

            if length(x)<0 || isempty(x)
                app.SliderCP.Limits = double([0 1]);
            else
                app.SliderCP.Limits = double([x(1) x(end)]);
            end

            if thisCP == 0 || isempty(ndx_CP) || ndx_CP < 1 || ndx_CP > length(x)
                app.SliderCP.Value = double(sum(app.SliderCP.Limits));
            else
                app.SliderCP.Value = double(x(ndx_CP));
            end

            D = app.AFMdata.D_Matrix{round(app.SliderRow.Value),changingValue};
            E = app.AFMdata.PWE_Matrix{round(app.SliderRow.Value),changingValue};
            semilogy(app.UIAxes_3,D(1:end),real(E(1:end)),'*')
            
            app.ColEditField.Value = num2str(changingValue);
            delete(app.xl)
            app.xl = xline(app.UIAxes,changingValue);
            
        end

        % Value changing function: SliderCP
        function SliderCPValueChanging(app, event)
            changingValue = event.Value;
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            
            [~,CPndx] = min(abs(x - changingValue));
            h = y(CPndx:end) - y(CPndx); % h - h0
            z = x(CPndx:end) - x(CPndx); % z - z0
            D = z - h; % depth = z-zo - h-h0
            F = h.*app.AFMdata.spring_constant;
            R = app.AFMdata.R;
            th = app.AFMdata.th;
            b = app.AFMdata.b;
            [E_app,regimeChange] = calc_E_app(D,F,R,th,b,'pointwise',0);
            E_app = E_app * 1e18 * 1e-9; % units of Pa
            E_app = E_app/1000; % units of kPa
            v = app.AFMdata.v;
            E = E_app .* 2 .* (1-v^2);
            
            semilogy(app.UIAxes_3,D(1:end),real(E(1:end)),'*')


            delete(app.cpline)
            app.cpline = xline(app.UIAxes_2,changingValue);
            app.CurrentCP = x(CPndx);
            app.CurrentD = D;
            app.CurrentF = F;
            app.CurrentPWE = E;
            app.CurrentE = E(end);
        end

        % Button pushed function: OverwriteCPButton
        function OverwriteCPButtonPushed(app, event)
            row = round(app.SliderRow.Value);
            col = round(app.SliderCol.Value);
            
            CP_Matrix = app.AFMdata.CP_Matrix;
            D_Matrix =  app.AFMdata.D_Matrix;
            F_Matrix = app.AFMdata.F_Matrix;
            PWE_Matrix = app.AFMdata.PWE_Matrix;
            E_Matrix = app.AFMdata.E_Matrix;
   
            
            CP_Matrix(row,col) = app.CurrentCP;
            D_Matrix{row,col} = app.CurrentD;
            F_Matrix{row,col} = app.CurrentF;
            PWE_Matrix{row,col} = app.CurrentPWE;
            E_Matrix(row,col) = app.CurrentE;
            Height_Matrix = max(CP_Matrix(:)) - CP_Matrix;
            
            save(app.fh,'CP_Matrix','D_Matrix','F_Matrix',"PWE_Matrix" ...
                ,'E_Matrix','Height_Matrix','-append');
     
            app.AFMdata = load(app.fh);
            value = app.HeightOrModulus.Value;
            if strcmp(value,"Height")
                imagesc(app.UIAxes,app.AFMdata.Height_Matrix);
                title(app.UIAxes,'Relative Height (nm)')
                c = colorbar(app.UIAxes); c.Label.String = 'nm';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            elseif strcmp(value,"Modulus")
                imagesc(app.UIAxes,app.AFMdata.E_Matrix);
                title(app.UIAxes,'Elastic Modulus (kPa)')
                c = colorbar(app.UIAxes); c.Label.String = 'kPa';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            else
                warning("This case shouldn't happen")
            end
            delete(app.yl)
            app.yl = yline(app.UIAxes,round(app.SliderRow.Value));
            delete(app.xl)
            app.xl = xline(app.UIAxes,round(app.SliderCol.Value));
            
            
        end

        % Value changed function: HeightOrModulus
        function HeightOrModulusValueChanged(app, event)
            value = app.HeightOrModulus.Value;
            if strcmp(value,"Height")
                imagesc(app.UIAxes,app.AFMdata.Height_Matrix);
                title(app.UIAxes,'Relative Height (nm)')
                c = colorbar(app.UIAxes); c.Label.String = 'nm';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            elseif strcmp(value,"Modulus")
                imagesc(app.UIAxes,app.AFMdata.E_Matrix);
                title(app.UIAxes,'Elastic Modulus (kPa)')
                c = colorbar(app.UIAxes); c.Label.String = 'kPa';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            else
                warning("This case shouldn't happen")
            end
            delete(app.yl)
            app.yl = yline(app.UIAxes,round(app.SliderRow.Value));
            delete(app.xl)
            app.xl = xline(app.UIAxes,round(app.SliderCol.Value));
        end

        % Button pushed function: NewModulusMapButton
        function NewModulusMapButtonPushed(app, event)
            depthToUse = app.IndentationDepthnmEditField.Value;
            [nRows, nCols] = size(app.AFMdata.Height_Matrix);
            newModulusMap = zeros(nRows,nCols);
            
            for i_row = 1:nRows
                for i_col = 1:nCols
                    D = app.AFMdata.D_Matrix{i_row,i_col};
                    PWE = app.AFMdata.PWE_Matrix{i_row,i_col};
                    if isempty(D)
                        newModulusMap(i_row,i_col) = nan;
                    elseif D(end) < depthToUse
                        newModulusMap(i_row,i_col) = PWE(end);
                    else
                        [~,minIdx] = min(abs(D-depthToUse));
                        newModulusMap(i_row,i_col) = PWE(minIdx);
                    end
                end
            end
            imagesc(app.UIAxes_4,newModulusMap);
            titles = sprintf('Modulus (kPa) @ D = %i nm',depthToUse);
            title(app.UIAxes_4,titles)
            c = colorbar(app.UIAxes_4); c.Label.String = 'kPa';
            set(app.UIAxes_4,'fontsize',12)
            set(app.UIAxes_4,'YDir','normal')
            axis(app.UIAxes_4, 'tight')
            caxis(app.UIAxes_4,[app.LowerCLEditField.Value app.UpperCLEditField.Value]);


        end

        % Button pushed function: UpdateClimitsButton
        function UpdateClimitsButtonPushed(app, event)
            caxis(app.UIAxes_4,[app.LowerCLEditField.Value app.UpperCLEditField.Value]);
        end

        % Button pushed function: AxisAutoButton
        function AxisAutoButtonPushed(app, event)
            axis(app.UIAxes_2,'tight')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.SelectAFMdatamatfileButton = uibutton(app.UIFigure, 'push');
            app.SelectAFMdatamatfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAFMdatamatfileButtonPushed, true);
            app.SelectAFMdatamatfileButton.Text = 'Select AFM data (.mat file)';
            app.SliderCol = uislider(app.UIFigure);
            app.SliderCol.ValueChangingFcn = createCallbackFcn(app, @SliderColValueChanging, true);
            app.SliderCol.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.SliderRow = uislider(app.UIFigure);
            app.SliderRow.ValueChangingFcn = createCallbackFcn(app, @SliderRowValueChanging, true);
            app.SliderRow.Value = 1;
            app.RowEditFieldLabel = uilabel(app.UIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.UIFigure, 'text');
            app.RowEditField.Value = '1';
            app.ColEditFieldLabel = uilabel(app.UIFigure);
            app.ColEditFieldLabel.Text = 'Col';
            app.ColEditField = uieditfield(app.UIFigure, 'text');
            app.ColEditField.Value = '1';
            app.SliderCP = uislider(app.UIFigure);
            app.SliderCP.Limits = [-10.4 100];
            app.SliderCP.ValueChangingFcn = createCallbackFcn(app, @SliderCPValueChanging, true);
            app.OverwriteCPButton = uibutton(app.UIFigure, 'push');
            app.OverwriteCPButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteCPButtonPushed, true);
            app.OverwriteCPButton.Text = {'Overwrite CP'; ''};
            app.CurrentFileTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentFileTextAreaLabel.Text = 'Current File';
            app.CurrentFileTextArea = uitextarea(app.UIFigure);
            app.HeightOrModulus = uiswitch(app.UIFigure, 'toggle');
            app.HeightOrModulus.Items = {'Height', 'Modulus'};
            app.HeightOrModulus.ValueChangedFcn = createCallbackFcn(app, @HeightOrModulusValueChanged, true);
            app.HeightOrModulus.Value = 'Height';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.IndentationDepthnmEditFieldLabel = uilabel(app.UIFigure);
            app.IndentationDepthnmEditFieldLabel.Text = 'Indentation Depth (nm)';
            app.IndentationDepthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.IndentationDepthnmEditField.Value = 1000;
            app.LowerCLEditFieldLabel = uilabel(app.UIFigure);
            app.LowerCLEditFieldLabel.Text = 'Lower CL ';
            app.LowerCLEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerCLEditField.Value = 3;
            app.UpperCLEditFieldLabel = uilabel(app.UIFigure);
            app.UpperCLEditFieldLabel.Text = 'Upper CL';
            app.UpperCLEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperCLEditField.Value = 30;
            app.NewModulusMapButton = uibutton(app.UIFigure, 'push');
            app.NewModulusMapButton.ButtonPushedFcn = createCallbackFcn(app, @NewModulusMapButtonPushed, true);
            app.NewModulusMapButton.Text = 'New Modulus Map';
            app.UpdateClimitsButton = uibutton(app.UIFigure, 'push');
            app.UpdateClimitsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateClimitsButtonPushed, true);
            app.UpdateClimitsButton.Text = 'Update Climits';
            app.AxisAutoButton = uibutton(app.UIFigure, 'push');
            app.AxisAutoButton.ButtonPushedFcn = createCallbackFcn(app, @AxisAutoButtonPushed, true);
            app.AxisAutoButton.Text = {'Axis Auto'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end